classdef customOpApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        Label            matlab.ui.control.Label
        lbUnit           matlab.ui.control.EditField
        calcBtt          matlab.ui.control.Button
        Panel            matlab.ui.container.Panel
        taStatus         matlab.ui.control.TextArea
        Label5           matlab.ui.control.Label
        plotFlag         matlab.ui.control.CheckBox
        Label6           matlab.ui.control.Label
        Label7           matlab.ui.control.Label
        LabelDropDown    matlab.ui.control.Label
        dB_tTH           matlab.ui.control.DropDown
        LabelDropDown2   matlab.ui.control.Label
        dbSignal1        matlab.ui.control.DropDown
        LabelDropDown3   matlab.ui.control.Label
        dbOperation      matlab.ui.control.DropDown
        Label2           matlab.ui.control.Label
        dbSignal2        matlab.ui.control.DropDown
        LabelEditField   matlab.ui.control.Label
        lbOutSignal      matlab.ui.control.EditField
        LabelEditField3  matlab.ui.control.Label
        lbDes            matlab.ui.control.EditField
        Label3           matlab.ui.control.Label
        extData          matlab.ui.control.DropDown
        loadExtData      matlab.ui.control.Button
    end


    properties (Access = public)
        UD % UserData
        tTHsel % tTH selezionata
        resPlot % Description
        extFunction % Description
    end

    methods (Access = public)
        
        function addInfo(app, info, add)
            oldInfo = get(app.taStatus, 'Value');
            switch add
                case 'clc'
                    oldInfo = {};
                    oldInfo{end+1} = info;
                case 'n'
                    oldInfo{end+1} = info;
                case 'add'
                    oldInfo{end} = [oldInfo{end}, info];
            end
            
            set(app.taStatus, 'Value', oldInfo);
        end


        function results = loadUserData(app, UserData)
            %% Nascondo i campi 
%             disp(nargin)
%             if nargin == 1
%                 UserData = struct('tTH', '');
%             end
            app.UD = UserData;
            disp(UserData)
            %% carico i dati
            app.updateDataFun;
            %% load operation option 
        end
        
        function results = updateDataFun(app)
            set(app.dB_tTH,'Items', fieldnames(app.UD.tTH));
            set(app.extData,'Items', {});
            app.dB_tTHValueChanged();    
        end
        
        function results = show_hide_app(app, status)
            set(app.UIFigure, 'Visible', status);   
        end
        
    end

    methods (Access = private)
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            assignin('base', 'app', app);
            app.show_hide_app('off');
            set(app.extData,'Visible', 'off');
            set(app.Label3, 'Visible', 'off');
            set(app.loadExtData, 'Visible', 'off');
            
            app.extFunction = '';
            opList = {'+', '-', '.*', './', '.^',...
                      '==', '~=', '>', '>=', '<', '<=', '&', '|', '~',...
                      'Integrate', 'Derivate', 'abs', 'sin', 'cos', 'asin', 'acos', 'tan', 'atan',...
                      '1D Interpolation', '2D Interpolation'};
            set(app.dbOperation, 'Items',opList);   
            app.loadUserData(struct('tTH', struct('tTH_1',struct('time',struct()))));
        end

        % Value changed function: dB_tTH
        function dB_tTHValueChanged(app, event)
            value = app.dB_tTH.Value;
            app.tTHsel = value;
            names = fieldnames(app.UD.tTH.(value));
            set(app.dbSignal2,'Items', names); app.dbSignal2ValueChanged();
            set(app.dbSignal1,'Items', names); app.dbSignal1ValueChanged();
        end

        % Value changed function: dbSignal1
        function dbSignal1ValueChanged(app, event)
            value = app.dbSignal1.Value;
            try 
                a = eval(value);
                set(app.dbSignal1, 'Value', num2str(a));
                set(app.Label6, 'Text', '-');
            catch ME
                switch ME.identifier 
                    case 'MATLAB:UndefinedFunction'
                        if ~isfield(app.UD.tTH.(app.tTHsel), value)
                            set(app.dbSignal1, 'Value', app.dbSignal1.Items{1});
                            warndlg('Signal not foud!');
                        else
                            if isfield(app.UD.tTH.(app.tTHsel).(value),'u')
                                set(app.Label6, 'Text', app.UD.tTH.(app.tTHsel).(value).u);
                            end
                        end
                    otherwise
                        set(app.dbSignal1, 'Value', app.dbSignal2.Items{1});
                        warndlg({'Signal not foud!', 'or operation not necognized'});
                        set(app.Label6, 'Text', '-');
                end
            end
        end

        % Value changed function: dbOperation
        function dbOperationValueChanged(app, event)
            value = app.dbOperation.Value;
            app = opSelectedUpWin(app);
        end

        % Value changed function: dbSignal2
        function dbSignal2ValueChanged(app, event)
            value = app.dbSignal2.Value;
            try 
                a = eval(value);
                set(app.dbSignal2, 'Value', num2str(a));
                set(app.Label7, 'Text', '-');
            catch ME
                switch ME.identifier 
                    case 'MATLAB:UndefinedFunction'
                        if ~isfield(app.UD.tTH.(app.tTHsel), value)
                            set(app.dbSignal2, 'Value', app.dbSignal2.Items{1});
                            warndlg('Signal not foud!')
                        end
                        if isfield(app.UD.tTH.(app.tTHsel).(value),'u')
                            set(app.Label7, 'Text', app.UD.tTH.(app.tTHsel).(value).u);
                        end
                    otherwise
                        set(app.dbSignal2, 'Value', app.dbSignal2.Items{1});
                        warndlg({'Signal not foud!', 'or operation not necognized'});
                        set(app.Label7, 'Text', '-');
                end
            end
            
        end

        % Value changed function: extData
        function extDataValueChanged(app, event)
            value = app.extData.Value;
        end

        % Button pushed function: calcBtt
        function calcBttButtonPushed(app, event)
            app.addInfo('Start evaluation...', 'clc');
            name = get(app.lbOutSignal, 'Value');
            if isvarname(name)
                app.addInfo(name, 'n');
                funCalcCstOp(app);
            else
            app.addInfo('Failed! Please infert a correct name!', 'n');    
            end
        end

        % Button pushed function: loadExtData
        function loadExtDataButtonPushed(app, event)
            if ~isfield(app.UD, 'extData')
                app.UD.extData = struct();
            end
            if  ~isfield(app.UD.extData, 'tab')
                app.UD.extData.tab = struct();
            end
                        
            app.UD.extData.tab = loadExtDataTab(app.UD.extData.tab);
            show_hide_app(app, 'off'); show_hide_app(app, 'on');
            extValue = fieldnames(app.UD.extData.tab); 
            set(app.extData,'Items', extValue);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
%             delete(app);
              show_hide_app(app, 'off');
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            setAutoResize(app, app.UIFigure, true)
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'tTH name:';
            app.lbUnit = uieditfield(app.UIFigure, 'text');
            app.lbUnit.Value = '-';
            app.calcBtt = uibutton(app.UIFigure, 'push');
            app.calcBtt.ButtonPushedFcn = createCallbackFcn(app, @calcBttButtonPushed, true);
            app.calcBtt.Text = 'Calculate';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Status';
            app.taStatus = uitextarea(app.Panel);
            app.taStatus.Editable = 'off';
            app.Label5 = uilabel(app.UIFigure);
            app.Label5.Text = 'Example: ';
            app.plotFlag = uicheckbox(app.UIFigure);
            app.plotFlag.Text = 'plot results';
            app.Label6 = uilabel(app.UIFigure);
            app.Label6.Text = 'lbuS1';
            app.Label7 = uilabel(app.UIFigure);
            app.Label7.Text = 'lbuS1';
            app.LabelDropDown = uilabel(app.UIFigure);
            app.LabelDropDown.Text = 'Select tTH';
            app.dB_tTH = uidropdown(app.UIFigure);
            app.dB_tTH.Items = {'Option 1', 'Option 2', 'Option 3'};
            app.dB_tTH.ValueChangedFcn = createCallbackFcn(app, @dB_tTHValueChanged, true);
            app.LabelDropDown2 = uilabel(app.UIFigure);
            app.LabelDropDown2.Text = 'X:';
            app.dbSignal1 = uidropdown(app.UIFigure);
            app.dbSignal1.Items = {};
            app.dbSignal1.Editable = 'on';
            app.dbSignal1.ValueChangedFcn = createCallbackFcn(app, @dbSignal1ValueChanged, true);
            app.dbSignal1.Value = {};
            app.LabelDropDown3 = uilabel(app.UIFigure);
            app.LabelDropDown3.Text = 'Select operation';
            app.dbOperation = uidropdown(app.UIFigure);
            app.dbOperation.Items = {'+', '-', '*', '/', '>', '<', 'Integrate', 'Derivate'};
            app.dbOperation.ValueChangedFcn = createCallbackFcn(app, @dbOperationValueChanged, true);
            app.dbOperation.Value = '-';
            app.Label2 = uilabel(app.UIFigure);
            app.Label2.Text = 'Y:';
            app.dbSignal2 = uidropdown(app.UIFigure);
            app.dbSignal2.Items = {};
            app.dbSignal2.Editable = 'on';
            app.dbSignal2.ValueChangedFcn = createCallbackFcn(app, @dbSignal2ValueChanged, true);
            app.dbSignal2.Value = {};
            app.LabelEditField = uilabel(app.UIFigure);
            app.LabelEditField.Text = 'out signal name:';
            app.lbOutSignal = uieditfield(app.UIFigure, 'text');
            app.LabelEditField3 = uilabel(app.UIFigure);
            app.LabelEditField3.Text = 'Description';
            app.lbDes = uieditfield(app.UIFigure, 'text');
            app.Label3 = uilabel(app.UIFigure);
            app.Label3.Text = 'external Data:';
            app.extData = uidropdown(app.UIFigure);
            app.extData.Items = {'+', '-', '*', '/', '>', '<', 'Integrate', 'Derivate'};
            app.extData.ValueChangedFcn = createCallbackFcn(app, @extDataValueChanged, true);
            app.extData.Value = '-';
            app.loadExtData = uibutton(app.UIFigure, 'push');
            app.loadExtData.ButtonPushedFcn = createCallbackFcn(app, @loadExtDataButtonPushed, true);
            app.loadExtData.Text = 'load Data';
        end
    end

    methods (Access = public)

        % Construct app
        function app = customOpApp()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end